classdef trajectory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GetTrajectoryButton          matlab.ui.control.Button
        ToLong_LatitudeButton        matlab.ui.control.Button
        EditField_3                  matlab.ui.control.EditField
        EditField_4                  matlab.ui.control.EditField
        EditField_5                  matlab.ui.control.EditField
        ClearButton                  matlab.ui.control.Button
        EditField_6                  matlab.ui.control.EditField
        ShowRawdataButton            matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        PrincipalInterestUIAxes      matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_2    matlab.ui.control.UIAxes
        PrincipalInterestUIAxes_3    matlab.ui.control.UIAxes
        TrajectoryNumEditFieldLabel  matlab.ui.control.Label
        TrajectoryNumEditField       matlab.ui.control.NumericEditField
        ChooseDatasetListBoxLabel    matlab.ui.control.Label
        ChooseDatasetListBox         matlab.ui.control.ListBox
        SizeEditFieldLabel           matlab.ui.control.Label
        SizeEditField                matlab.ui.control.NumericEditField
        MeanShiftClusterButton       matlab.ui.control.Button
        ToMercatorButton             matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        EditField_2                  matlab.ui.control.EditField
        BandwidthEditFieldLabel      matlab.ui.control.Label
        BandwidthEditField           matlab.ui.control.NumericEditField
        ShowBWEditFieldLabel         matlab.ui.control.Label
        ShowBWEditField              matlab.ui.control.NumericEditField
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {316, 316};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: MeanShiftClusterButton
        function MeanShiftClusterButtonPushed(app, event)
            
            bandWidth1 = app.BandwidthEditField.Value;
            app.ShowBWEditField.Value = bandWidth1;
            datasetname_1 = [app.ChooseDatasetListBox.Value,num2str(001),'.xlsx']; %%%%%列表框选择数据集%%%%%
            datasetsize1 = ['A2:A',num2str(app.SizeEditField.Value)];
            datasetsize2 = ['B2:B',num2str(app.SizeEditField.Value)];
            datasetsize3 = ['C2:C',num2str(app.SizeEditField.Value)];
            x=xlsread(datasetname_1,'Sheet1',datasetsize1);
            y=xlsread(datasetname_1,'Sheet1',datasetsize2);
            z=xlsread(datasetname_1,'Sheet1',datasetsize3);
            
            %             x=xlsread('Anthus_spragueii001.xlsx','Sheet1','A2:A2142');
            %             y=xlsread('Anthus_spragueii001.xlsx','Sheet1','B2:B2142');
            %             z=xlsread('Anthus_spragueii001.xlsx','Sheet1','C2:C2142');
            N = size(z);
            N=N(1,1);
            zz1=z(1,1);
            zz2=zz1+366;
            zz3=1;
            for i=zz1:zz2
                zz4=0;
                for i1=1:N
                    if i==z(i1,:)
                        aa(zz3,1)=x(i1,:);
                        aa(zz3,2)=y(i1,:);
                        aa(zz3,3)=z(i1,:);
                        zz3=zz3+1;
                        zz4=zz4+1;
                    end
                end
                if zz4==0
                    aa(zz3,3)=i;
                    zz3=zz3+1;
                end
                
            end
            aa1=aa;
            N1 = size(aa1);
            N1=N1(1,1);
            zz5=0;
            for i2=1:N1
                if aa1(i2,1)==0
                    zz5=zz5+1;
                end
                if aa1(i2,1)~=0&zz5>0
                    
                    zz6=i2;
                    for i3=1:zz5
                        qq1=(aa1(i2,1)-aa1(i2-(zz5+1),1))/(zz5+1);
                        qq2=(aa1(i2,2)-aa1(i2-(zz5+1),2))/(zz5+1);
                        aa1(i2-(zz5+1)+i3,1)=aa1(i2-(zz5+1),1)+qq1*i3;
                        aa1(i2-(zz5+1)+i3,2)=aa1(i2-(zz5+1),2)+qq2*i3;
                        
                    end
                    zz5=0;
                end
                
            end
            aaaa1=size(aa1,1);
            if aa(aaaa1,1)==0
                for i3=1:zz5
                    qq1=aa1(i2-zz5,1)-aa1((i2-zz5-1),1);
                    qq2=aa1(i2-zz5,2)-aa1((i2-zz5-1),2);
                    aa1(i2-zz5+i3,1)=aa1(i2-zz5,1)+qq1*i3;
                    aa1(i2-zz5+i3,2)=aa1(i2-zz5,2)+qq2*i3;
                    
                end
            end
            
            x=aa1(:,1);
            y=aa1(:,2);
            z=aa1(:,3);
            N = size(z);
            AA=[];
            AA(1,:)=[x(1),y(1)];
            h=1;
            L=1;
            
            for i=1:N-1
                if z(i+1)==z(i)
                    AA(h,2*L+1:2*L+2)=[x(i+1),y(i+1)];
                    L=L+1;
                else
                    h=h+1;
                    L=1;
                    AA(h,L:L+1)=[x(i+1),y(i+1)];
                end
            end
            AA(all(AA==0,2),:)=[]
            N1= size(AA,2);
            N1=N1/2;
            N2=size(AA,1);
            a1=1;a2=1,a3=1; A1=[];A2=[];A3=[];
            %改变BB的的值来循环
            for bb=1:N2
                if bb<=N2-7
                    if bb==N2-7
                        cc=bb;
                    end
                    AAA=[];
                    new=1;
                    for gg=bb:bb+6
                        for ii=1:N1
                            if AA(gg,2*ii-1)~=0&&AA(gg,2*ii)~=0
                                AAA(new,1)=AA(gg,2*ii-1);
                                AAA(new,2)=AA(gg,2*ii);
                                new=new+1;
                            end
                        end
                    end
                end
                
                if bb>N2-7
                    AAA=[];
                    new=1;
                    bb=cc;
                    for gg=bb:bb+6
                        for ii=1:N1
                            if AA(gg,2*ii-1)~=0&&AA(gg,2*ii)~=0
                                AAA(new,1)=AA(gg,2*ii-1);
                                AAA(new,2)=AA(gg,2*ii);
                                new=new+1;
                            end
                        end
                    end
                end
                
                x=AAA;
                n= size(AAA,1);;  % 生成数据点数
                % figure(1)
                % scatter(x(:,1),x(:,2));
                
                %% 归一化处理
                x_min=min(min(x));
                x_max=max(max(x));
                x=(x-x_min)/(x_max-x_min);
                % figure(2)
                % scatter(x(:,1),x(:,2));
                %% 划分网格，聚类，裁剪数据
                %% 注 论文里说不同维度，根据其分布，取不同的k值,对数据进行划分。但根本没有写怎么做的
                % 而且，论文里不同维度取不同k值的原因是，如果k取得过小，会导致计算量过大
                % 这个k，即使统计出分布也不好定，因为统计分布其实就是划分再计数，
                % 现在这里就是k直接给定。
                %% 划分网络
                k=50;
                len=1/k;
                position_x=zeros(n,2);
                position_x(:,1)=ceil(x(:,1)/len);
                position_x(:,2)=ceil(x(:,2)/len);
                position_x(find(position_x==0))=1;
                [A,B]=sortrows(position_x);
                %% 统计各网格内点的数量。
                count=zeros(k,k);
                for i=1:n
                    count(A(i,1),A(i,2))=count(A(i,1),A(i,2))+1;
                end
                %% 裁剪数据
                % 设计阙值
                % 在了解数据分布前，阙值不好给出，这里依然是比例
                % 阙值=最大数*比例
                q=2;
                max_count=max(max(count));
                q=q*max_count;
                jishu=1;
                for i=1:n
                    if count(A(i,1),A(i,2))>=q
                        xx(jishu,1)=x(B(i),1);
                        xx(jishu,2)=x(B(i),2);
                        x(B(i),1)=-1;
                        x(B(i),2)=-1;
                        jishu=jishu+1;
                    end
                end
                x=x(find(x(:,1)~=-1),1:2);
                %% 裁剪后的数据视图
                % figure
                % scatter(x(:,1),x(:,2));
                %% 后面和第三章一样了
                %% 带权值计算空间对象距离 （这里是欧氏距离）
                [n,~]=size(x);
                w=[0.5,0.5]; %自己给
                % w=rand(1,2); %Calcarius_ornatusCalcarius_ornatusCalcarius_ornatus或者随机生成
                % w=w/sum(w);
                dist=zeros(n,n);
                for i=1:n
                    dist(:,i)= w(1)*( (x(:,1)-x(i,1)) .^2 )  + w(2)*( (x(:,2)-x(i,2)).^2 ) ;
                end
                dist=sqrt(dist);
                %% 计算每个对象的空间邻域
                max_dist=max(max(dist));
                k=1;   %% 论文里是直接给一个k值(不好给，也不直观)，我改成k是一个比率，最终阙值为k*max_dist(最大距离)
                k=k*max_dist; %把这句话删了就和原文一样了
                [N_i,N_j]=find(dist<=k);
                N=[N_i,N_j];
                %% 计算每个对象的SLDR
                u=zeros(1,n);
                SLDR=zeros(1,n);
                for i=1:n
                    % disp(i);
                    tmp=find(N_j==i);
                    tmp_E=max(tmp);
                    tmp_S=min(tmp);
                    tmp=dist( N(tmp_S:tmp_E,1:2)) ;
                    tmp_ji=(tmp_E-tmp_S+1);
                    u(i)=sum(tmp(:,1))/tmp_ji;
                    tmp_c=(tmp(:,1)-u(i)).^2;
                    SLDR(i)=sum(tmp_c)/tmp_ji;
                end
                %% 获取离群点候选集。 论文这里的显然有问题，由它的定义，SLDR其实是领域内各点距离的方差，这基本上不可能出现0
                % 只有一个领域内所有点距离距离相等才可能为0，这明显不可能
                % 先空着。。。，不影响整体程序
                %% 计算每个对象的SLDIR
                SLDIR=zeros(1,n);
                for i=1:n
                    % disp(i)
                    tmp=find(N_j==i);
                    tmp_E=max(tmp);
                    tmp_S=min(tmp);
                    tmp=SLDR( N_i(tmp_S:tmp_E) ) ;
                    tmp_ji=(tmp_E-tmp_S+1);
                    SLDIR(i)=sum(tmp)/tmp_ji;
                end
                %% 计算每个对象的SLDF
                SLDF=SLDIR./SLDR;
                %% 绘制结果（）
                k=round(0.1*n); % 看一下SLDF_1 （倒序），你会发现在达到一定数量后，所有的SLDF值相同了，如果发现有错误点，可能是k取得过大。
                [SLDF_1,B]=sort(SLDF);
                % figure
                % scatter(x(B(1:n-k),1),x(B(1:n-k),2));
                % hold on;
                % scatter(x(B(n-k+1:n),1),x(B(1+n-k:n),2),'r');
                % hold on;
                BB=[x(B(1:n-k),1),x(B(1:n-k),2)];
                BB=BB*(x_max-x_min)+x_min;
                % scatter(xx(:,1),xx(:,2));
                x=BB';
                bandwidth = bandWidth1;
                %% 聚类
                tic
                [clustCent,point2cluster,clustMembsCell] = MeanShiftCluster(x,bandwidth);
                % clustCent：聚类中心 D*K, point2cluster：聚类结果 类标签, 1*N
                toc
                %% 作图
                numClust = length(clustMembsCell);
                A3(:,a3)=numClust;
                a3=a3+1;
                
                %               set(gcf,'Visible','off');
                %               clf,hold on ;
                %%%%在axes中显示最终的聚类结果(plot)%%%%
                myhandle = figure(3);
                set(myhandle,'Visible','off');
                cla(app.PrincipalInterestUIAxes,"reset");
                hold(app.PrincipalInterestUIAxes,"on");
                
                % figure(3),clf,hold on
                cVec = 'bgrcmykbgrcmykbgrcmykbgrcmyk';%, cVec = [cVec cVec];
                for k = 1:min(numClust,length(cVec))
                    myMembers = clustMembsCell{k};
                    myClustCen = clustCent(:,k);
                    plot(app.PrincipalInterestUIAxes,x(1,myMembers),x(2,myMembers),[cVec(k) '.'])
                    plot(app.PrincipalInterestUIAxes,myClustCen(1),myClustCen(2),'o','MarkerEdgeColor','k','MarkerFaceColor',cVec(k), 'MarkerSize',10)
                    AAA1= size(myMembers,2);
                    A1(:,a1)=myClustCen;
                    a1=a1+1;
                    if AAA1>1
                        for aaa=1:AAA1
                            A2(aaa,2*a2-1:2*a2)=[x(1,myMembers(aaa)),x(2,myMembers(aaa))];
                        end
                        a2=a2+1;
                    end
                    if AAA1==1
                        A2(AAA1,2*a2-1:2*a2)=[x(1,myMembers(AAA1)),x(2,myMembers(AAA1))];
                        a2=a2+1;
                    end
                end
                set(app.PrincipalInterestUIAxes,'fontname','Time New Romans','fontsize',14);
                title(app.PrincipalInterestUIAxes,['no shifting, numClust:' int2str(numClust)]);
                xlabel(app.PrincipalInterestUIAxes,'X');
                ylabel(app.PrincipalInterestUIAxes,'Y');
                
                % 显示每一张图，但是很慢
                %                 saveas(gcf,'my.jpg');
                %                 axis(app.PrincipalInterestUIAxes,'off'); %隐藏坐标轴
                %                 myimage = imread('my.jpg');
                %                 image(app.PrincipalInterestUIAxes,myimage);
                
                %数据储存
                A4=A3;
                A3(find(A3==0))=[];
            end
            
            % 只显示最后一张图
            %             saveas(gcf,'my.jpg');
            %             axis(app.PrincipalInterestUIAxes,'off'); %隐藏坐标轴
            %             myimage = imread('my.jpg');
            %             image(app.PrincipalInterestUIAxes,myimage);
            disp('hello world!');
            
            %数据比较
            NMAX= size(AAA,1);
            A3(:,NMAX)=0;
            A3(find(A3==0))=[];
            p2=0;
            N3=size(A3,2);
            for p1=1:N3
                PP=A3(1,p1);
                p2=PP+p2;
                A3(2,p1)=p2;
            end
            LL4=0;dddd=0;LL5=0;LL6=0;
            for b1=2:N3-1
                if LL6>0
                    LL1=A3(2,b1-1);
                    LL2=A3(2,b1);
                    LL3=A3(2,b1+1);
                    O1=A3(1,b1-1);
                    O2=new2;
                    O3=A3(1,b1+1);
                    KK1=A1(:,LL4+1:LL1);
                    KK2=new1;
                    KK3=A1(:,LL2+1:LL3);
                    LL4=LL1;
                    LL5=LL5+1;
                end
                if LL6==0
                    LL1=A3(2,b1-1);
                    LL2=A3(2,b1);
                    LL3=A3(2,b1+1);
                    O1=A3(1,b1-1);
                    O2=A3(1,b1);
                    O3=A3(1,b1+1);
                    KK1=A1(:,LL4+1:LL1);
                    KK2=A1(:,LL1+1:LL2);
                    KK3=A1(:,LL2+1:LL3);
                    LL4=LL1;
                    LL5=LL5+1;
                end
                %过度
                guodu1= size(KK2,2);
                for pp1=1:guodu1
                    kk2=KK2(:,pp1);
                    kk2=kk2';
                    KKK2(:,pp1*2-1:pp1*2)=kk2;
                end
                guodu2= size(KK3,2);
                for pp1=1:guodu2
                    kk3=KK3(:,pp1);
                    kk3=kk3';
                    KKK3(:,pp1*2-1:pp1*2)=kk3;
                end
                %大型
                jl=[];shuju0=[];
                if O3>O2
                    new2=O3;
                    dddd=dddd+1;
                    for t1=1:O2
                        for t2=1:O3
                            jl(t1,t2)=sqrt((KK2(1,t1)-KK3(1,t2))^2+(KK2(2,t1)-KK3(2,t2))^2);
                        end
                    end
                    [min_a,index]=min(jl,[],2);
                    for t3=1:O2
                        aaa=index(t3);
                        if dddd==1
                            shuju1=[KKK2(:,t3*2-1:t3*2);KKK3(:,aaa*2-1:aaa*2)];
                            new1(:,t3)=KK3(:,aaa);
                            LL6=LL6+1;
                        end
                        if dddd>1
                            shuju1=[];
                            shuju1=[guiji{t3};KKK3(:,aaa*2-1:aaa*2)];
                            new1(:,t3)=KK3(:,aaa);
                        end
                        zhongjian{t3}=shuju1;
                        zhongjian{t3}(all(shuju1==0,2),:)=[];
                    end
                    
                    
                    jl=[];
                    for t1=1:O3
                        for t2=1:O2
                            jl(t1,t2)=sqrt((KK3(1,t1)-KK2(1,t2))^2+(KK3(2,t1)-KK2(2,t2))^2);
                        end
                    end
                    [min_a,index]=min(jl,[],2);
                    XX=unique(index);
                    nnp=O3-O2;
                    nnn=1;
                    for i=1:length(XX)
                        [m n]=find(index==XX(i));
                        if length(m)>=2
                            abc(nnn,1)=XX(i) ;
                            abc(nnn,2)=length(m);
                            nnn=nnn+1;
                        end
                        if length(m)>=2
                            for nnc=1:nnp
                                if nnn-1>0
                                    aaa=index(abc(nnn-1,1));
                                end
                                if nnn-1>1
                                    aaa=index(abc(nnn-1,1));
                                    nnn=nnn-1;
                                end
                                if dddd>1
                                    shuju1=[guiji{abc(nnn-1,1)};KKK3(:,aaa*2-1:aaa*2)];
                                end
                                if dddd==1
                                    shuju1=[KKK2(:,abc(nnn-1,1)*2-1:abc(nnn-1,1)*2);KKK3(:,aaa*2-1:aaa*2)];
                                end
                                new1(:,O2+nnc)=KK3(:,aaa);
                                zhongjian{O2+nnc}=shuju1;
                                zhongjian{O2+nnc}(all(shuju1==0,2),:)=[];
                                
                            end
                            
                        end
                        
                    end
                    for   t10=1:O3
                        guiji{t10}= zhongjian{t10};
                    end
                end
                %shuju2(all(shuju2==0,2),:)=[];
                
                %小型
                if O3<=O2
                    new2=O2;
                    dddd=dddd+1;
                    for t1=1:O2
                        for t2=1:O3
                            jl(t1,t2)=sqrt((KK2(1,t1)-KK3(1,t2))^2+(KK2(2,t1)-KK3(2,t2))^2);
                        end
                    end
                    [min_a,index]=min(jl,[],2);
                    
                    
                    for t3=1:O2
                        aaa=index(t3);
                        if dddd==1
                            shuju1=[KKK2(:,t3*2-1:t3*2);KKK3(:,aaa*2-1:aaa*2)];
                            new1(:,t3)=KK3(:,aaa);
                            LL6=LL6+1;
                        end
                        if dddd>1
                            shuju1=[];
                            shuju1=[guiji{t3};KKK3(:,aaa*2-1:aaa*2)];
                            new1(:,t3)=KK3(:,aaa);
                            LL6=LL6+1;
                        end
                        guiji{t3}=shuju1;
                        guiji{t3}(all(shuju1==0,2),:)=[];
                        
                    end
                end
            end
            
            shuzuN = size(guiji,2);
            for i=1:shuzuN
                aa=guiji{i};
                xlswrite('.\guiji1.csv',aa(:,1),'guiji1','A2:A366');
                xlswrite('.\guiji1.csv',aa(:,2),'guiji1','B2:B366');
                Rpath = 'D:\动物迁徙\拟合\R\R-3.6.3\R-3.6.3\bin'; %R语言目录
                RscriptFileName ='.\XXXXX1.R'; %R程序目录
                RunRcode(RscriptFileName, Rpath);
                load('jieguo1.mat')
                X=jieguo1.Y;
                RscriptFileName = '.\XXXXX2.R'; %R程序目录
                RunRcode(RscriptFileName, Rpath);
                load('jieguo2.mat')
                Y=jieguo2.Y;
                niheGUIJI{i}=[X Y];
            end
            for j=1:shuzuN
                BB=niheGUIJI{1,j};
                xlsxname = ['.\niheGUIJI',num2str(j),'.xlsx'];
                xlswrite(xlsxname,BB(:,1),'A1:A365');
                xlswrite(xlsxname,BB(:,2),'B1:B365');
            end
            app.TrajectoryNumEditField.Value = shuzuN;
            %             BB=niheGUIJI{1,1};
            %             xlswrite('D:\MatlabR2019b\workspace\guijitest\niheGUIJI.xlsx',BB(:,1),'A1:A365');
            %             xlswrite('D:\MatlabR2019b\workspace\guijitest\niheGUIJI.xlsx',BB(:,2),'B1:B365');
            
            app.EditField_2.Value = "ok";
            
        end

        % Button pushed function: GetTrajectoryButton
        function GetTrajectoryButtonPushed(app, event)
            % axis(app.PrincipalInterestUIAxes_2,'off'); %隐藏坐标轴
            
            load coast;
            %绘制全球海岸线
            %             figure(1)
            % plot(app.PrincipalInterestUIAxes_2,long,lat,'color',[0,0,0],'LineWidth',1);
            %hold(app.PrincipalInterestUIAxes_2,"on");
            axis(app.PrincipalInterestUIAxes_2,'equal'); axis(app.PrincipalInterestUIAxes_2,'tight'); grid(app.PrincipalInterestUIAxes_2,"on");
            set(app.PrincipalInterestUIAxes_2,'fontname','Time New Romans','fontsize',14,...
                'tickdir','out','ticklength',[0.02,0.02],...
                'gridlinestyle','-.','linewidth',0.1,...
                'xtick',[-180:60:180],'ytick',[-90:30:90],...
                'xticklabel',{'-180W','-120W','-60W','0','60E','120E','180E'},...
                'yticklabel',{' ','60S','30S','0','30N','60N'});
            xlabel(app.PrincipalInterestUIAxes_2,'Longitude');
            ylabel(app.PrincipalInterestUIAxes_2,'Latitude');
            shuzuN = app.TrajectoryNumEditField.Value;
            color = ['r','g','b','m','y','k','c','r','g'];
            
            for f = 1:shuzuN
                tuname = ['.\AA',num2str(f),'.xlsx'];
                lon1=xlsread(tuname,'Sheet1','A1:A365');
                lat1=xlsread(tuname,'Sheet1','B1:B365');
                
                aa = plot(app.PrincipalInterestUIAxes_2,lat1,lon1,'Color',color(f));
                hold(app.PrincipalInterestUIAxes_2,"on");
                
                groupname = ['Group ',num2str(f)];
                mystr{f} = groupname;
                %legend(app.PrincipalInterestUIAxes_2,[ aa ],groupname);
            end
            plot(app.PrincipalInterestUIAxes_2,long,lat,'color',[0,0,0],'LineWidth',1);           
            legend(app.PrincipalInterestUIAxes_2,mystr);
            
            figure(1)
            hold on;
            axis equal;axis tight;grid on;
            set(gca,'fontname','Time New Romans','fontsize',15,...
            'tickdir','out','ticklength',[0.02,0.02],...
            'gridlinestyle','-.','linewidth',0.1,...
            'xtick',[-180:60:180],'ytick',[-90:30:90],...
            'xticklabel',{'-180W','-120W','-60W','0','60E','120E','180E'},...
            'yticklabel',{' ','60S','30S','0','30N','60N'});
            xlabel('Longitude');
            ylabel('Latitude');
           

            color = ['r','g','b','m','y','k','c'];
for f = 1:shuzuN
    tuname = ['.\AA',num2str(f),'.xlsx'];
    lon1=xlsread(tuname,'Sheet1','A1:A365'); 
    lat1=xlsread(tuname,'Sheet1','B1:B365'); 

% lon2=xlsread('AA1.xlsx','Sheet1','A1:A365'); 
% lat2=xlsread('AA1.xlsx','Sheet1','B1:B365');  
% lon3=xlsread('AA2.xlsx','Sheet1','A1:A365'); 
% lat3=xlsread('AA2.xlsx','Sheet1','B1:B365');  
% lon4=xlsread('AA3.xlsx','Sheet1','A1:A365'); 
% lat4=xlsread('AA3.xlsx','Sheet1','B1:B365');  
% lon5=xlsread('AA4.xlsx','Sheet1','A1:A365'); 
% lat5=xlsread('AA4.xlsx','Sheet1','B1:B365');  
% lon6=xlsread('Passerina_amoena6.xlsx','Sheet1','B2:B366'); 
% lat6=xlsread('Passerina_amoena6.xlsx','Sheet1','A2:A366');  

    aa = plot(lat1,lon1,'Color',color(f))
    
%aa=plot(lat1,lon1 ,'color',[0,1,1]); %线性，颜色，标记
% bb=plot(lat2,lon2,'b' ); %线性，颜色，标记
% cc=plot(lat3,lon3,'color',[0.5,0,0]); %线性，颜色，标记
% dd=plot(lat4,lon4,'r' ); %线性，颜色，标记
% ee=plot(lat5,lon5,'y'); %线性，颜色，标记
% ff=plot(lon6,lat6,'m' ); %线性，颜色，标记

     groupname = ['Group ',num2str(f)];
     mystr{f} = groupname;
     %legend(aa,groupname);
end
plot(long,lat,'color',[0,0,0],'LineWidth',1);
legend(mystr);
            app.EditField_4.Value = "ok";
            disp('welcome!');
        end

        % Button pushed function: ToMercatorButton
        function ToMercatorButtonPushed(app, event)
            
            AA=[];
            mstruct=defaultm('mercator');
            mstruct.geoid=[ 6378137,0.0818191908426215];
            mstruct.origin=[0,0,0];
            mstruct=defaultm(mstruct);
            
            datasetname = ['C:\Users\ad\Desktop\guijitest\Data in brief\',app.ChooseDatasetListBox.Value,'.xlsx']; %%%%%列表框选择数据集%%%%%
            datasetsize1 = ['A2:A',num2str(app.SizeEditField.Value)];
            datasetsize2 = ['B2:B',num2str(app.SizeEditField.Value)];
            datasetsize3 = ['C2:C',num2str(app.SizeEditField.Value)];
            x=xlsread(datasetname,'Sheet1',datasetsize1);
            y=xlsread(datasetname,'Sheet1',datasetsize2);
            z=xlsread(datasetname,'Sheet1',datasetsize3);
            
            %             x=xlsread('Anthus_spragueii.xlsx','Anthus_spragueii','A2:A2142');
            %             y=xlsread('Anthus_spragueii.xlsx','Anthus_spragueii','B2:B2142');
            %             z=xlsread('Anthus_spragueii.xlsx','Anthus_spragueii','C2:C2142');
            N = size(x,1);
            for i=1:N  %比下面少1
                lat=x(i,1);
                lon=y(i,1);
                date=z(i,1);
                [xx,yy] =projfwd(mstruct,lat,lon);
                AA(i,1)=xx;
                AA(i,2)=yy;
                AA(i,3)=date;
                %[lat,lon]=projinv(mstruct,x,y)
            end
            datasetname_1 = [app.ChooseDatasetListBox.Value,num2str(001),'.xlsx'];
            xlswrite(datasetname_1,AA(:,1),datasetsize1);
            xlswrite(datasetname_1,AA(:,2),datasetsize2);
            xlswrite(datasetname_1,AA(:,3),datasetsize3);
            app.EditField.Value = "ok";
            
        end

        % Button pushed function: ToLong_LatitudeButton
        function ToLong_LatitudeButtonPushed(app, event)
            AAA=[];
            mstruct=defaultm('mercator');
            mstruct.geoid=[ 6378137,0.0818191908426215];
            mstruct.origin=[0,0,0];
            mstruct=defaultm(mstruct);
            
            shuzuN = app.TrajectoryNumEditField.Value;
            for k=1:shuzuN
                xlsxname = ['.\niheGUIJI',num2str(k),'.xlsx'];
                X=xlsread(xlsxname,'Sheet1','A1:A365');
                y=xlsread(xlsxname,'Sheet1','B1:B365');
                
                N = size(X,1);
                for i=1:N  %比下面少1
                    xx=X(i,1);
                    yy=y(i,1);
                    [lat,lon]=projinv(mstruct,xx,yy);
                    AAA(i,1)=lat;
                    AAA(i,2)=lon;
                end
                AAname = ['.\AA',num2str(k),'.xlsx'];
                xlswrite(AAname,AAA(:,1),'A1:A365');
                xlswrite(AAname,AAA(:,2),'B1:B365')          
                              
            end
            
            app.EditField_3.Value = "ok";
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.PrincipalInterestUIAxes,"reset");
            cla(app.PrincipalInterestUIAxes_2,"reset");
            cla(app.PrincipalInterestUIAxes_3,"reset");
            title(app.PrincipalInterestUIAxes, 'Cluster');
            xlabel(app.PrincipalInterestUIAxes, 'X');
            ylabel(app.PrincipalInterestUIAxes, 'Y');
            title(app.PrincipalInterestUIAxes_2, 'Trajectory');
            xlabel(app.PrincipalInterestUIAxes_2, 'Longitude');
            ylabel(app.PrincipalInterestUIAxes_2, 'Latitude');
            title(app.PrincipalInterestUIAxes_3, 'Raw Data');
            xlabel(app.PrincipalInterestUIAxes_3, 'Longitude');
            ylabel(app.PrincipalInterestUIAxes_3, 'Latitude');
            app.EditField.Value = "";
            app.EditField_2.Value = "";
            app.EditField_3.Value = "";
            app.EditField_4.Value = "";
            app.EditField_6.Value = "";
            app.SizeEditField.Value = 0;
            app.ShowBWEditField.Value = 1;
            app.TrajectoryNumEditField.Value = 1;
            app.EditField_5.Value = "ok";
            pause(2);
            app.EditField_5.Value = "";
            
        end

        % Button pushed function: ShowRawdataButton
        function ShowRawdataButtonPushed(app, event)
            load coast;
            %绘制全球海岸线
            %figure(1)
            plot(app.PrincipalInterestUIAxes_3,long,lat,'color',[0,0,0],'LineWidth',1);
            hold(app.PrincipalInterestUIAxes_3,"on");
            axis(app.PrincipalInterestUIAxes_3,'equal'); axis(app.PrincipalInterestUIAxes_3,'tight'); grid(app.PrincipalInterestUIAxes_3,"on");
            
            set(app.PrincipalInterestUIAxes_3,'fontname','Time New Romans','fontsize',14,...
                'tickdir','out','ticklength',[0.02,0.02],...
                'gridlinestyle','-.','linewidth',0.1,...
                'xtick',[-180:60:180],'ytick',[-90:30:90],...
                'xticklabel',{'-180W','-120W','-60W','0','60E','120E','180E'},...
                'yticklabel',{' ','60S','30S','0','30N','60N'});
            xlabel(app.PrincipalInterestUIAxes_3,'Longitude');
            ylabel(app.PrincipalInterestUIAxes_3,'Latitude');
            
            datasetname = ['C:\Users\ad\Desktop\guijitest\Data in brief\',app.ChooseDatasetListBox.Value,'.xlsx']; %%%%%列表框选择数据集%%%%%
            datasetsize1 = ['A2:A',num2str(app.SizeEditField.Value)];
            datasetsize2 = ['B2:B',num2str(app.SizeEditField.Value)];
            datasetsize3 = ['C2:C',num2str(app.SizeEditField.Value)];
            
            lon1=xlsread(datasetname,'Sheet1',datasetsize1);
            lat1=xlsread(datasetname,'Sheet1',datasetsize2);
            c=xlsread(datasetname,'Sheet1',datasetsize3);
            N=size(c,1);
            MON=1;
            CS=0;
            for pp=1:N-1
                if c(pp)<=c(1)+MON*31
                    CS=CS+1;
                end
                if c(pp)>c(1)+MON*31
                    MON=MON+1;
                    CS=1;
                end
                MO(CS,2*MON-1)=lon1(pp);
                MO(CS,2*MON)=lat1(pp);
            end
            j1=MO(:,1);
            j1(find(j1==0))=[];
            j2=MO(:,2);
            j2(find(j2==0))=[];
            
            
            j3=MO(:,3);
            j3(find(j3==0))=[];
            j4=MO(:,4);
            j4(find(j4==0))=[];
            
            
            j5=MO(:,5);
            j5(find(j5==0))=[];
            j6=MO(:,6);
            j6(find(j6==0))=[];
            
            j7=MO(:,7);
            j7(find(j7==0))=[];
            j8=MO(:,8);
            j8(find(j8==0))=[];
            
            
            j9=MO(:,9);
            j9(find(j9==0))=[];
            j10=MO(:,10);
            j10(find(j10==0))=[];
            
            
            j11=MO(:,11);
            j11(find(j11==0))=[];
            j12=MO(:,12);
            j12(find(j12==0))=[];
            
            
            j13=MO(:,13);
            j13(find(j13==0))=[];
            j14=MO(:,14);
            j14(find(j14==0))=[];
            
            
            j15=MO(:,15);
            j15(find(j15==0))=[];
            j16=MO(:,16);
            j16(find(j16==0))=[];
            
            
            j17=MO(:,17);
            j17(find(j17==0))=[];
            j18=MO(:,18);
            j18(find(j18==0))=[];
            
            
            j19=MO(:,19);
            j19(find(j19==0))=[];
            j20=MO(:,20);
            j20(find(j20==0))=[];
            
            
            j21=MO(:,21);
            j21(find(j21==0))=[];
            j22=MO(:,22);
            j22(find(j22==0))=[];
            
            
            j23=MO(:,23);
            j23(find(j23==0))=[];
            j24=MO(:,24);
            j24(find(j24==0))=[];
            
            
            tu1=scatter(app.PrincipalInterestUIAxes_3,j2,j1,'.', 'b');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu2=scatter(app.PrincipalInterestUIAxes_3,j4,j3,'.', 'b');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu3=scatter(app.PrincipalInterestUIAxes_3,j6,j5,'.', 'b');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu4=scatter(app.PrincipalInterestUIAxes_3,j8,j7,'.', 'k');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu5=scatter(app.PrincipalInterestUIAxes_3,j10,j9,'.', 'r');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu6=scatter(app.PrincipalInterestUIAxes_3,j12,j11,'.', 'r');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu7=scatter(app.PrincipalInterestUIAxes_3,j14,j13,'.', 'r');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu8=scatter(app.PrincipalInterestUIAxes_3,j16,j15,'.', 'r');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu9=scatter(app.PrincipalInterestUIAxes_3,j18,j17,'.', 'm');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu10=scatter(app.PrincipalInterestUIAxes_3,j20,j19,'.', 'c');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu11=scatter(app.PrincipalInterestUIAxes_3,j22,j21,'.', 'k');
            hold(app.PrincipalInterestUIAxes_3,"on");
            tu12=scatter(app.PrincipalInterestUIAxes_3,j24,j23,'.', 'b');
            
            legend(app.PrincipalInterestUIAxes_3,[ tu1 tu2 tu3 tu4 tu5 tu6 tu7 tu8 tu9 tu10 tu11 tu12],'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec')
            
            figure(2)
            plot(long,lat,'color',[0,0,0],'LineWidth',1);hold on;
            axis equal;axis tight;grid on;
            set(gca,'fontname','Time New Romans','fontsize',15,...
           'tickdir','out','ticklength',[0.02,0.02],...
           'gridlinestyle','-.','linewidth',0.1,...
           'xtick',[-180:60:180],'ytick',[-90:30:90],...
           'xticklabel',{'-180W','-120W','-60W','0','60E','120E','180E'},...
           'yticklabel',{' ','60S','30S','0','30N','60N'});
            xlabel('Longitude');
            ylabel('Latitude');
            figure(2)
            tu1=scatter(j2,j1,'.', 'b');
            hold on;
            tu2=scatter(j4,j3,'.', 'b');
            hold on;
            tu3=scatter(j6,j5,'.', 'b');
            hold on;
            tu4=scatter(j8,j7,'.', 'k');
            hold on;
            tu5=scatter(j10,j9,'.', 'r');
            hold on;
            tu6=scatter(j12,j11,'.', 'r');
            hold on;
            tu7=scatter(j14,j13,'.', 'r');
            hold on;
            tu8=scatter(j16,j15,'.', 'r');
            hold on;
            tu9=scatter(j18,j17,'.', 'm');
            hold on;
            tu10=scatter(j20,j19,'.', 'c');
            hold on;
            tu11=scatter(j22,j21,'.', 'k');
            hold on;
            tu12=scatter(j24,j23,'.', 'b');
            legend([ tu1 tu2 tu3 tu4 tu5 tu6 tu7 tu8 tu9 tu10 tu11 tu12],'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec')

            app.EditField_6.Value = "ok";
            
        end

        % Value changed function: ChooseDatasetListBox
        function ChooseDatasetListBoxValueChanged(app, event)
            value = app.ChooseDatasetListBox.Value;
            if value == "Ammospiza_nelsoni"
                app.SizeEditField.Value = 6050;
            elseif value == "Anthus_spragueii"
                app.SizeEditField.Value = 2141;    
            elseif value == "Calcarius_ornatus"
                app.SizeEditField.Value = 3514; 
            elseif value == "Cardellina_canadensis"
                app.SizeEditField.Value = 10824; 
            elseif value == "Catharus_minimus"
                app.SizeEditField.Value = 21437; 
            elseif value == "Contopus_cooperi"
                app.SizeEditField.Value = 14023; 
            elseif value == "Elaenia_parvirostris"
                app.SizeEditField.Value = 2943; 
            elseif value == "Empidonax_difficilis"
                app.SizeEditField.Value = 12838; 
            elseif value == "Euphagus_carolinus"
                app.SizeEditField.Value = 12164; 
            elseif value == "Geothlypis_philadelphia"
                app.SizeEditField.Value = 7505; 
            elseif value == "Helmitheros_vermivorum"
                app.SizeEditField.Value = 6874; 
            elseif value == "Icterus_spurius"
                app.SizeEditField.Value = 25277; 
            elseif value == "Muscisaxicola_capistratus"
                app.SizeEditField.Value = 200; 
            elseif value == "Passerina_amoena"
                app.SizeEditField.Value = 11937; 
            elseif value == "Vireo_philadelphicus"
                app.SizeEditField.Value = 22020; 
                
                
            end
        end

        % Button down function: PrincipalInterestUIAxes_3
        function PrincipalInterestUIAxes_3ButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GetTrajectoryButton = uibutton(app.LeftPanel, 'push');
            app.GetTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetTrajectoryButtonPushed, true);
            app.GetTrajectoryButton.Text = 'GetTrajectory';
            app.ToLong_LatitudeButton = uibutton(app.LeftPanel, 'push');
            app.ToLong_LatitudeButton.ButtonPushedFcn = createCallbackFcn(app, @ToLong_LatitudeButtonPushed, true);
            app.ToLong_LatitudeButton.Text = 'ToLong_Latitude';
            app.EditField_3 = uieditfield(app.LeftPanel, 'text');
            app.EditField_4 = uieditfield(app.LeftPanel, 'text');
            app.EditField_5 = uieditfield(app.LeftPanel, 'text');
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.EditField_6 = uieditfield(app.LeftPanel, 'text');
            app.ShowRawdataButton = uibutton(app.LeftPanel, 'push');
            app.ShowRawdataButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRawdataButtonPushed, true);
            app.ShowRawdataButton.Text = 'ShowRawdata';
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Cluster')
            xlabel(app.PrincipalInterestUIAxes, 'X')
            ylabel(app.PrincipalInterestUIAxes, 'Y')
            app.PrincipalInterestUIAxes_2 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_2, 'Trajectory')
            xlabel(app.PrincipalInterestUIAxes_2, 'Longitude')
            ylabel(app.PrincipalInterestUIAxes_2, 'Latitude')
            app.PrincipalInterestUIAxes_3 = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes_3, 'Raw Data')
            xlabel(app.PrincipalInterestUIAxes_3, 'Longitude')
            ylabel(app.PrincipalInterestUIAxes_3, 'Latitude')
            app.PrincipalInterestUIAxes_3.ButtonDownFcn = createCallbackFcn(app, @PrincipalInterestUIAxes_3ButtonDown, true);
            app.TrajectoryNumEditFieldLabel = uilabel(app.MortgageCalculatorUIFigure);
            app.TrajectoryNumEditFieldLabel.Text = 'TrajectoryNum';
            app.TrajectoryNumEditField = uieditfield(app.MortgageCalculatorUIFigure, 'numeric');
            app.TrajectoryNumEditField.Limits = [0 10];
            app.TrajectoryNumEditField.ValueDisplayFormat = '%8.f';
            app.ChooseDatasetListBoxLabel = uilabel(app.MortgageCalculatorUIFigure);
            app.ChooseDatasetListBoxLabel.Text = 'Choose Dataset';
            app.ChooseDatasetListBox = uilistbox(app.MortgageCalculatorUIFigure);
            app.ChooseDatasetListBox.Items = {'Ammospiza_nelsoni', 'Anthus_spragueii', 'Calcarius_ornatus', 'Cardellina_canadensis', 'Catharus_minimus', 'Contopus_cooperi', 'Elaenia_parvirostris', 'Empidonax_difficilis', 'Euphagus_carolinus', 'Geothlypis_philadelphia', 'Icterus_spurius', 'Muscisaxicola_capistratus', 'Passerina_amoena', 'Vireo_philadelphicus', 'Helmitheros_vermivorum'};
            app.ChooseDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseDatasetListBoxValueChanged, true);
            app.ChooseDatasetListBox.Value = 'Ammospiza_nelsoni';
            app.SizeEditFieldLabel = uilabel(app.MortgageCalculatorUIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.MortgageCalculatorUIFigure, 'numeric');
            app.SizeEditField.Limits = [0 100000];
            app.SizeEditField.ValueDisplayFormat = '%8.f';
            app.MeanShiftClusterButton = uibutton(app.MortgageCalculatorUIFigure, 'push');
            app.MeanShiftClusterButton.ButtonPushedFcn = createCallbackFcn(app, @MeanShiftClusterButtonPushed, true);
            app.MeanShiftClusterButton.Text = 'MeanShiftCluster';
            app.ToMercatorButton = uibutton(app.MortgageCalculatorUIFigure, 'push');
            app.ToMercatorButton.ButtonPushedFcn = createCallbackFcn(app, @ToMercatorButtonPushed, true);
            app.ToMercatorButton.Text = 'ToMercator';
            app.EditField = uieditfield(app.MortgageCalculatorUIFigure, 'text');
            app.EditField_2 = uieditfield(app.MortgageCalculatorUIFigure, 'text');
            app.BandwidthEditFieldLabel = uilabel(app.MortgageCalculatorUIFigure);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.MortgageCalculatorUIFigure, 'numeric');
            app.BandwidthEditField.Limits = [0 10000000];
            app.BandwidthEditField.ValueDisplayFormat = '%8.f';
            app.BandwidthEditField.Value = 800000;
            app.ShowBWEditFieldLabel = uilabel(app.MortgageCalculatorUIFigure);
            app.ShowBWEditFieldLabel.Text = 'ShowBW';
            app.ShowBWEditField = uieditfield(app.MortgageCalculatorUIFigure, 'numeric');
            app.ShowBWEditField.Limits = [0 10000000];
            app.ShowBWEditField.ValueDisplayFormat = '%8.f';
            app.ContextMenu = uicontextmenu(app.MortgageCalculatorUIFigure);
            % Assign app.ContextMenu
            app.ChooseDatasetListBox.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trajectory

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end